classdef app1editedTAM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MaskDetectorUIFigure  matlab.ui.Figure
        UsetheoutlineespeciallyforyourchinforbestresultsLabel  matlab.ui.control.Label
        UseLiveCameraButton   matlab.ui.control.Button
        ORLabel               matlab.ui.control.Label
        Lamp                  matlab.ui.control.Lamp
        EditField             matlab.ui.control.EditField
        Image                 matlab.ui.control.Image
        EmergencycasedetectionusingImageProcessingMASKLabel  matlab.ui.control.Label
        SnapButton            matlab.ui.control.Button
        UploadphotoButton     matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        cam % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SnapButton
        function SnapButtonPushed(app, event)
            cam1=webcam;
            g=snapshot(cam1);
            app.Image.ImageSource=g;
            q=size(g);
            p=g(:,fix(q(2)/4):fix((q(2)*3)/4),:);
            load('avgcolor');
            a=p; % the wanted image to be examined.
            b=size(a);
            c=a(fix(b(1)/2):fix((b(1)*3)/4),fix(b(2)/3):fix((2*b(2))/3),:); %cropping the image.
            d=double(c);
            e=mean(mean(d)); %blending the colors.
            wantedavg=uint8(e); %actual avarage color.
            %% comparison with black
            avgblacklab=rgb2lab(avgblack);
            wantedavglab=rgb2lab(wantedavg);
            DeltaE(1)=sqrt(sum((avgblacklab-wantedavglab).^2));
            %% comparison with blue
            avgblacklab=rgb2lab(avgblue);
            DeltaE(2)=sqrt(sum((avgblacklab-wantedavglab).^2));
            %% comparison with green
            avgblacklab=rgb2lab(avggreen);
            DeltaE(3)=sqrt(sum((avgblacklab-wantedavglab).^2));
            %% comparison with white
            avgblacklab=rgb2lab(avgwhite);
            DeltaE(4)=sqrt(sum((avgblacklab-wantedavglab).^2));
            %% comparison with neutral
            avgblacklab=rgb2lab(avgneutral);
            DeltaE(5)=sqrt(sum((avgblacklab-wantedavglab).^2));
            %% result
            i=find(DeltaE==min(DeltaE));
            if i==5
                app.EditField.Value='The person in the given image is not wearing a mask.';
                app.Lamp.Color='r';
            else
                app.EditField.Value='The person in the given image is wearing a mask.';
                app.Lamp.Color='g';
            end
        end

        % Button pushed function: UploadphotoButton
        function UploadphotoButtonPushed(app, event)
            [file,path]=uigetfile({'*.jpg';'*.png';'*.jfif'});
            cd(path);    
            load('avgcolor');
            a=imread(file); % the wanted image to be examined.
            app.Image.ImageSource=a;
            b=size(a);
            c=a(fix(b(1)/2):fix((b(1)*3)/4),fix(b(2)/3):fix((2*b(2))/3),:); %cropping the image.
            d=double(c);
            e=mean(mean(d)); %blending the colors.
            wantedavg=uint8(e); %actual avarage color.
            %% comparison with black
            avgblacklab=rgb2lab(avgblack);
            wantedavglab=rgb2lab(wantedavg);
            DeltaE(1)=sqrt(sum((avgblacklab-wantedavglab).^2));
            %% comparison with blue
            avgblacklab=rgb2lab(avgblue);
            DeltaE(2)=sqrt(sum((avgblacklab-wantedavglab).^2));
            %% comparison with green
            avgblacklab=rgb2lab(avggreen);
            DeltaE(3)=sqrt(sum((avgblacklab-wantedavglab).^2));
            %% comparison with white
            avgblacklab=rgb2lab(avgwhite);
            DeltaE(4)=sqrt(sum((avgblacklab-wantedavglab).^2));
            %% comparison with neutral
            avgblacklab=rgb2lab(avgneutral);
            DeltaE(5)=sqrt(sum((avgblacklab-wantedavglab).^2));
            %% result
            i=find(DeltaE==min(DeltaE));
            if i==5
               app.EditField.Value='The person in the given image is not wearing a mask.';
               app.Lamp.Color='r';
            else
               app.EditField.Value='The person in the given image is wearing a mask.';
               app.Lamp.Color='g';
            end
        end

        % Button pushed function: UseLiveCameraButton
        function UseLiveCameraButtonPushed(app, event)
            app.Lamp.Color='w';
            app.EditField.Value='';
            app.Image.ImageSource='yüzşeması.png';
            app.cam=webcam;
            frame=snapshot(app.cam);
            im=image(app.UIAxes, zeros(size(frame),'uint8'));
            axis(app.UIAxes,'image');
            preview(app.cam,im);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MaskDetectorUIFigure = uifigure('Visible', 'off');
            app.MaskDetectorUIFigure.Name = 'Mask Detector';
            app.MaskDetectorUIFigure.Icon = 'mask2 - Kopya.png';
            app.UIAxes = uiaxes(app.MaskDetectorUIFigure);
            app.UIAxes.XLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UploadphotoButton = uibutton(app.MaskDetectorUIFigure, 'push');
            app.UploadphotoButton.ButtonPushedFcn = createCallbackFcn(app, @UploadphotoButtonPushed, true);
            app.UploadphotoButton.Text = 'Upload photo';
            app.SnapButton = uibutton(app.MaskDetectorUIFigure, 'push');
            app.SnapButton.ButtonPushedFcn = createCallbackFcn(app, @SnapButtonPushed, true);
            app.SnapButton.Icon = 'camera-512.png';
            app.SnapButton.Text = '';
            app.EmergencycasedetectionusingImageProcessingMASKLabel = uilabel(app.MaskDetectorUIFigure);
            app.EmergencycasedetectionusingImageProcessingMASKLabel.Text = 'Emergency case detection using Image Processing: MASK';
            app.Image = uiimage(app.MaskDetectorUIFigure);
            app.Image.ImageSource = 'output-onlinepngtools.png';
            app.EditField = uieditfield(app.MaskDetectorUIFigure, 'text');
            app.EditField.Editable = 'off';
            app.Lamp = uilamp(app.MaskDetectorUIFigure);
            app.ORLabel = uilabel(app.MaskDetectorUIFigure);
            app.ORLabel.Text = 'OR';
            app.UseLiveCameraButton = uibutton(app.MaskDetectorUIFigure, 'push');
            app.UseLiveCameraButton.ButtonPushedFcn = createCallbackFcn(app, @UseLiveCameraButtonPushed, true);
            app.UseLiveCameraButton.Text = 'Use webcam';
            app.UsetheoutlineespeciallyforyourchinforbestresultsLabel = uilabel(app.MaskDetectorUIFigure);
            app.UsetheoutlineespeciallyforyourchinforbestresultsLabel.Text = 'Use the outline(especially for your chin) for best results.';
            % Show the figure after all components are created
            app.MaskDetectorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1editedTAM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MaskDetectorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MaskDetectorUIFigure)
        end
    end
end